classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        PrototypeTab                    matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        RsultatsTab                     matlab.ui.container.Tab
        ConfigurationPanel              matlab.ui.container.Panel
        AvancButton                     matlab.ui.control.Button
        PositionducapteurEditField_2Label  matlab.ui.control.Label
        PositionducapteurEditField      matlab.ui.control.EditField
        PositiondelactuateurEditField_2Label  matlab.ui.control.Label
        PositiondelactuateurEditField   matlab.ui.control.EditField
        PolaritdelartroactionEditFieldLabel  matlab.ui.control.Label
        PolaritdelartroactionEditField  matlab.ui.control.EditField
        IntrantsPanel                   matlab.ui.container.Panel
        DuredesimulationEditFieldLabel  matlab.ui.control.Label
        DuredesimulationEditField       matlab.ui.control.NumericEditField
        FrquenceHzEditFieldLabel        matlab.ui.control.Label
        FrquenceHzEditField             matlab.ui.control.EditField
        MasseliniqueEditFieldLabel      matlab.ui.control.Label
        MasseliniqueEditField           matlab.ui.control.EditField
        LongueurEditFieldLabel          matlab.ui.control.Label
        LongueurEditField               matlab.ui.control.EditField
        NoteListBoxLabel                matlab.ui.control.Label
        NoteListBox                     matlab.ui.control.ListBox
        LesAccords2Label                matlab.ui.control.Label
        SimulerButton                   matlab.ui.control.Button
        EnregistrerButton               matlab.ui.control.Button
        ChargerButton                   matlab.ui.control.Button
        RinitialiserButton              matlab.ui.control.Button
        StatutLabel                     matlab.ui.control.Label
        SpinnerLabel                    matlab.ui.control.Label
        Spinner                         matlab.ui.control.Spinner
    end

    properties (Access = private)
        SimulationParams SimulationParameterManager;% Description
        Logger ErrorLogger;
        simulatorManager SimulatorManager;
    end
    
    methods (Access = private)
        
        function results = ParseDoublesStrOrExpr(app, value)
            try
                splittedValue =  strsplit(value,';');
                valuesAsDouble = cellfun( @(x) str2double(x),splittedValue);
                if(size(valuesAsDouble,2)==1)
                    if(isnan(valuesAsDouble))
                        results = eval(value);
                    else
                        results(1) = valuesAsDouble;    
                    end                   
                elseif(any(arrayfun(@(x) isnan(x), valuesAsDouble)))
                    results = eval(value);
                else
                    results = valuesAsDouble;
                end
            catch ME
               app.Logger.Warn(ME.message);
               results = double.empty(0); 
            end            
        end
                
        function SetStatut(app, statut)
            app.StatutLabel.Text = sprintf('Statut : %s', mlreportgen.utils.toString(statut));
        end
    end
    
    methods (Static)
        function resultStr = FormatDoubleArrayToString(values)
            resultStr = strjoin(arrayfun(@(value) sprintf('%.3g',value), values, 'UniformOutput', false),';');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Logger = ErrorLogger(app.StatutLabel, app.UIFigure);
            app.SimulationParams = SimulationParameterManager();
            simulator = SystemSimulator('ModelCompletSimple');
            app.simulatorManager = SimulatorManager(simulator);
            app.Reset(); %Set everything to default.
        end

        % Button pushed function: SimulerButton
        function Simulate(app, event)
            %Set status
            app.SimulerButton.Enable = false;
            app.ChargerButton.Enable = false;
            %Start simulation
            app.SetStatut(app.SimulationParams.GetNumberOfSimulation());
            
            app.simulatorManager.Simulate(app.SimulationParams.EnumerateSimulationParameters());
        end

        % Button pushed function: RinitialiserButton
        function Reset(app, event)
            %Set component status
            app.SimulerButton.Enable = true;
            app.ChargerButton.Enable = true;
            
            %Set default value. Might want to make a method out of this. 
            % -> Factory
            %They have to respect matlab array or split with ';' - Never
            %mind though. Pos could be multiple of L though.
            app.SimulationParams.Duration = 10;
            app.SimulationParams.Frequence = (110);
            app.SimulationParams.Length = (0.00325);
            app.SimulationParams.LinearDensity = (0.443);
            
            app.DuredesimulationEditField.Value = 10;           
            app.FrquenceHzEditField.Value = '110';
            app.NoteListBox.Value = 'La';
            app.MasseliniqueEditField.Value = '0.00325';
            app.LongueurEditField.Value = '0.443';
            app.PositionducapteurEditField.Value = '';
            app.PositiondelactuateurEditField.Value = '';
            app.PolaritdelartroactionEditField.Value = '';
            
            %Set statut
            app.SetStatut("Prêt.");
        end

        % Value changed function: NoteListBox
        function NoteDropDownValueChanged(app, event)
            %Check with team for functionality. Maybe they did something
            %regarding this part in the other UI. Otherwise, maybe  a class
            %with a list of notes and frequency (See spreadsheet)
            switch app.NoteListBox.Value
                case 'Do'
                    app.FrquenceHzEditField.Value = '120';
                otherwise
                    app.FrquenceHzEditField.Value = '240';
            end
        end

        % Value changed function: FrquenceHzEditField
        function EditFrequence(app, event)
            results = ParseDoublesStrOrExpr(app, app.FrquenceHzEditField.Value);           
            app.SimulationParams.Frequence = results;
            app.FrquenceHzEditField.Value = app1.FormatDoubleArrayToString(results);
        end

        % Value changed function: MasseliniqueEditField
        function EditMassLineique(app, event)
            value = app.MasseliniqueEditField.Value;
            results = ParseDoublesStrOrExpr(app, value);           
            app.SimulationParams.Frequence = results;
            app.MasseliniqueEditField.Value = app1.FormatDoubleArrayToString(results);
        end

        % Value changed function: LongueurEditField
        function EditLongueur(app, event)
            value = app.LongueurEditField.Value;
            results = ParseDoublesStrOrExpr(app, value);           
            app.SimulationParams.Frequence = results;
            app.LongueurEditField.Value = app1.FormatDoubleArrayToString(results);
        end

        % Value changed function: DuredesimulationEditField
        function EditDuree(app, event)
            app.SimulationParams.Duration = app.DuredesimulationEditField.Value;
        end

        % Value changed function: PositionducapteurEditField
        function EditPositionCapteur(app, event)
            value = app.PositionducapteurEditField.Value;            
            results = ParseDoublesStrOrExpr(app, value);           
            app.SimulationParams.Frequence = results;
            app.PositionducapteurEditField.Value = app1.FormatDoubleArrayToString(results);
        end

        % Value changed function: PositiondelactuateurEditField
        function EditPositionActuateur(app, event)
            value = app.PositiondelactuateurEditField.Value;
            results = ParseDoublesStrOrExpr(app, value);           
            app.SimulationParams.Frequence = results;
            app.PositiondelactuateurEditField.Value = app1.FormatDoubleArrayToString(results);
        end

        % Value changed function: PolaritdelartroactionEditField
        function PolaritdelartroactionEditFieldValueChanged(app, event)
            value = app.PolaritdelartroactionEditField.Value;
            results = ParseDoublesStrOrExpr(app, value);           
            app.SimulationParams.Frequence = results;
            app.PolaritdelartroactionEditField.Value = app1.FormatDoubleArrayToString(results);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PrototypeTab = uitab(app.TabGroup);
            app.PrototypeTab.Title = 'Prototype';
            app.Image = uiimage(app.PrototypeTab);
            app.Image.ImageSource = 'Design2-SystemeblocIO.jpg';
            app.RsultatsTab = uitab(app.TabGroup);
            app.RsultatsTab.Title = 'Résultats';
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.AvancButton = uibutton(app.ConfigurationPanel, 'push');
            app.AvancButton.Text = 'Avancé';
            app.PositionducapteurEditField_2Label = uilabel(app.ConfigurationPanel);
            app.PositionducapteurEditField_2Label.Text = 'Position du capteur';
            app.PositionducapteurEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.PositionducapteurEditField.ValueChangedFcn = createCallbackFcn(app, @EditPositionCapteur, true);
            app.PositiondelactuateurEditField_2Label = uilabel(app.ConfigurationPanel);
            app.PositiondelactuateurEditField_2Label.Text = 'Position de l''actuateur';
            app.PositiondelactuateurEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.PositiondelactuateurEditField.ValueChangedFcn = createCallbackFcn(app, @EditPositionActuateur, true);
            app.PolaritdelartroactionEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.PolaritdelartroactionEditFieldLabel.Text = 'Polarité de la rétroaction';
            app.PolaritdelartroactionEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.PolaritdelartroactionEditField.ValueChangedFcn = createCallbackFcn(app, @PolaritdelartroactionEditFieldValueChanged, true);
            app.IntrantsPanel = uipanel(app.UIFigure);
            app.IntrantsPanel.Title = 'Intrants';
            app.DuredesimulationEditFieldLabel = uilabel(app.IntrantsPanel);
            app.DuredesimulationEditFieldLabel.Text = 'Durée de simulation';
            app.DuredesimulationEditField = uieditfield(app.IntrantsPanel, 'numeric');
            app.DuredesimulationEditField.ValueChangedFcn = createCallbackFcn(app, @EditDuree, true);
            app.FrquenceHzEditFieldLabel = uilabel(app.IntrantsPanel);
            app.FrquenceHzEditFieldLabel.Text = 'Fréquence (Hz)';
            app.FrquenceHzEditField = uieditfield(app.IntrantsPanel, 'text');
            app.FrquenceHzEditField.ValueChangedFcn = createCallbackFcn(app, @EditFrequence, true);
            app.MasseliniqueEditFieldLabel = uilabel(app.IntrantsPanel);
            app.MasseliniqueEditFieldLabel.Text = 'Masse linéique';
            app.MasseliniqueEditField = uieditfield(app.IntrantsPanel, 'text');
            app.MasseliniqueEditField.ValueChangedFcn = createCallbackFcn(app, @EditMassLineique, true);
            app.LongueurEditFieldLabel = uilabel(app.IntrantsPanel);
            app.LongueurEditFieldLabel.Text = 'Longueur';
            app.LongueurEditField = uieditfield(app.IntrantsPanel, 'text');
            app.LongueurEditField.ValueChangedFcn = createCallbackFcn(app, @EditLongueur, true);
            app.NoteListBoxLabel = uilabel(app.IntrantsPanel);
            app.NoteListBoxLabel.Text = 'Note';
            app.NoteListBox = uilistbox(app.IntrantsPanel);
            app.NoteListBox.Items = {'Do', 'Ré', 'Mi', 'Fa', 'Sol', 'La', 'Si'};
            app.NoteListBox.Multiselect = 'on';
            app.NoteListBox.ValueChangedFcn = createCallbackFcn(app, @NoteDropDownValueChanged, true);
            app.NoteListBox.Value = {'Do'};
            app.LesAccords2Label = uilabel(app.UIFigure);
            app.LesAccords2Label.Text = 'Les Accords2';
            app.SimulerButton = uibutton(app.UIFigure, 'push');
            app.SimulerButton.ButtonPushedFcn = createCallbackFcn(app, @Simulate, true);
            app.SimulerButton.Text = 'Simuler';
            app.EnregistrerButton = uibutton(app.UIFigure, 'push');
            app.EnregistrerButton.Enable = 'off';
            app.EnregistrerButton.Text = 'Enregistrer';
            app.ChargerButton = uibutton(app.UIFigure, 'push');
            app.ChargerButton.Enable = 'off';
            app.ChargerButton.Text = 'Charger';
            app.RinitialiserButton = uibutton(app.UIFigure, 'push');
            app.RinitialiserButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.RinitialiserButton.Text = 'Réinitialiser';
            app.StatutLabel = uilabel(app.UIFigure);
            app.StatutLabel.Text = 'Statut';
            app.SpinnerLabel = uilabel(app.UIFigure);
            app.SpinnerLabel.Text = 'Spinner';
            app.Spinner = uispinner(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end